classdef DRONEMEAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        fixedstepEditField       matlab.ui.control.NumericEditField
        fixedstepEditFieldLabel  matlab.ui.control.Label
        stoptimeEditField        matlab.ui.control.NumericEditField
        stoptimeEditFieldLabel   matlab.ui.control.Label
        StartSimButton           matlab.ui.control.Button
        LOADVARIABLEButton       matlab.ui.control.Button
        w4EditField              matlab.ui.control.NumericEditField
        w4EditFieldLabel         matlab.ui.control.Label
        w3EditField              matlab.ui.control.NumericEditField
        w3EditFieldLabel         matlab.ui.control.Label
        w2EditField              matlab.ui.control.NumericEditField
        w2EditFieldLabel         matlab.ui.control.Label
        w1EditField              matlab.ui.control.NumericEditField
        w1EditFieldLabel         matlab.ui.control.Label
        zEditField               matlab.ui.control.NumericEditField
        zEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.NumericEditField
        xEditFieldLabel          matlab.ui.control.Label
        dzEditField              matlab.ui.control.NumericEditField
        dzEditFieldLabel         matlab.ui.control.Label
        yawEditField             matlab.ui.control.NumericEditField
        yawEditFieldLabel        matlab.ui.control.Label
        yEditField               matlab.ui.control.NumericEditField
        yEditFieldLabel          matlab.ui.control.Label
        dxEditField              matlab.ui.control.NumericEditField
        dxEditFieldLabel         matlab.ui.control.Label
        dyEditField              matlab.ui.control.NumericEditField
        dyEditFieldLabel         matlab.ui.control.Label
        dyawEditField            matlab.ui.control.NumericEditField
        dyawEditFieldLabel       matlab.ui.control.Label
        pitchEditField           matlab.ui.control.NumericEditField
        pitchEditFieldLabel      matlab.ui.control.Label
        rollEditField            matlab.ui.control.NumericEditField
        rollEditFieldLabel       matlab.ui.control.Label
        dpitchEditField          matlab.ui.control.NumericEditField
        dpitchEditFieldLabel     matlab.ui.control.Label
        drollEditField           matlab.ui.control.NumericEditField
        drollEditFieldLabel      matlab.ui.control.Label
        Panel2                   matlab.ui.container.Panel
        yawCheckBox              matlab.ui.control.CheckBox
        dyawCheckBox             matlab.ui.control.CheckBox
        pitchCheckBox            matlab.ui.control.CheckBox
        dpitchCheckBox           matlab.ui.control.CheckBox
        rollCheckBox             matlab.ui.control.CheckBox
        drollCheckBox            matlab.ui.control.CheckBox
        UIAxesN                  matlab.ui.control.UIAxes
        Panel                    matlab.ui.container.Panel
        zCheckBox                matlab.ui.control.CheckBox
        dzCheckBox               matlab.ui.control.CheckBox
        yCheckBox                matlab.ui.control.CheckBox
        dyCheckBox               matlab.ui.control.CheckBox
        xCheckBox                matlab.ui.control.CheckBox
        dxCheckBox               matlab.ui.control.CheckBox
        UIAxesE                  matlab.ui.control.UIAxes
        DropDown                 matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        eta; % Description
        iks;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADVARIABLEButton
        function LOADVARIABLEButtonPushed(app, event)
            droll0=app.drollEditField.Value;
            roll0=app.rollEditField.Value;
            roll0 = deg2rad(roll0);
            dpitch0=app.dpitchEditField.Value;
            pitch0=app.pitchEditField.Value;
            pitch0=deg2rad(pitch0);
            dyaw0=app.dyawEditField.Value;
            yaw0=app.dyawEditField.Value;
            yaw0=deg2rad(yaw0);
            dx0=app.dxEditField.Value;
            x0=app.xEditField.Value;
            dy0=app.dyEditField.Value;
            y0=app.yEditField.Value;
            dz0=app.dzEditField.Value;
            z0=app.zEditField.Value;
            w1=app.w1EditField.Value;
            w2=app.w2EditField.Value;
            w3=app.w3EditField.Value;
            w4=app.w4EditField.Value;
            inits=[droll0,roll0,dpitch0,pitch0,dyaw0,yaw0,dx0,x0,dy0,y0,dz0,z0];
            assignin("base","w1",w1);
            assignin("base","w2",w2);
            assignin("base","w3",w3);
            assignin("base","w4",w4);
            assignin("base",'inits',inits);
           rot_matris = [cos(yaw0)*cos(pitch0) (cos(yaw0)*sin(pitch0)*sin(roll0))-(sin(yaw0)*cos(roll0)) (cos(yaw0)*sin(pitch0)*cos(roll0))+(sin(yaw0)*sin(roll0));
            sin(yaw0)*cos(pitch0) (sin(yaw0)*sin(pitch0)*sin(roll0))+(cos(yaw0)*cos(roll0)) (sin(yaw0)*sin(pitch0)*cos(roll0))-(cos(yaw0)*sin(roll0));
            -sin(pitch0) cos(pitch0)*sin(roll0) cos(pitch0)*cos(roll0)];
           
            assignin ('base','rot_matris',rot_matris);
            
        end

        % Button pushed function: StartSimButton
        function StartSimButtonPushed(app, event)
            mdl="droneme";
            open_system(mdl);
            tstop = app.stoptimeEditField.Value;
            if tstop<0.5
                tstop =0.5;
            end
            FixedStep=app.fixedstepEditField.Value;
            if FixedStep<0.001
                FixedStep=0.001;
            end
            set_param(mdl,"StopTime",num2str(tstop),"FixedStep",num2str(FixedStep));
            simout=sim(mdl);
            assignin("base","simout",simout);
            time=simout.t;
            droll=simout.z(:,1);
            roll=simout.z(:,2);
            dpitch=simout.z(:,3);
            pitch=simout.z(:,4);
            dyaw=simout.z(:,5);
            yaw=simout.z(:,6);
            dx=simout.z(:,7);
            x=simout.z(:,8);
            dy=simout.z(:,9);
            y=simout.z(:,10);
            dz=simout.z(:,11);
            z=simout.z(:,12);
            app.eta=plot(app.UIAxesE,time,dx,time,x,time,dy,time,y,time,dz,time,z,"Visible","off");
            app.iks=plot(app.UIAxesN,time,droll,time,roll,time,dpitch,time,pitch,time,dyaw,time,yaw,"Visible","off");
        end

        % Value changed function: dxCheckBox
        function dxCheckBoxValueChanged(app, event)
            value = app.dxCheckBox.Value;
            if value == 0
                app.eta(1).Visible="off";
            else
                app.eta(1).Visible="on";
            end
        end

        % Value changed function: xCheckBox
        function xCheckBoxValueChanged(app, event)
            value = app.xCheckBox.Value;
             if value == 0
                app.eta(2).Visible="off";
            else
                app.eta(2).Visible="on";
            end
        end

        % Value changed function: dyCheckBox
        function dyCheckBoxValueChanged(app, event)
            value = app.dyCheckBox.Value;
             if value == 0
                app.eta(3).Visible="off";
            else
                app.eta(3).Visible="on";
            end
        end

        % Value changed function: yCheckBox
        function yCheckBoxValueChanged(app, event)
            value = app.yCheckBox.Value;
             if value == 0
                app.eta(4).Visible="off";
            else
                app.eta(4).Visible="on";
            end
        end

        % Value changed function: dzCheckBox
        function dzCheckBoxValueChanged(app, event)
            value = app.dzCheckBox.Value;
             if value == 0
                app.eta(5).Visible="off";
            else
                app.eta(5).Visible="on";
            end
        end

        % Value changed function: zCheckBox
        function zCheckBoxValueChanged(app, event)
            value = app.zCheckBox.Value;
             if value == 0
                app.eta(6).Visible="off";
            else
                app.eta(6).Visible="on";
            end
        end

        % Value changed function: drollCheckBox
        function drollCheckBoxValueChanged(app, event)
            value = app.drollCheckBox.Value;
             if value == 0
                app.iks(1).Visible="off";
            else
                app.iks(1).Visible="on";
            end
        end

        % Value changed function: rollCheckBox
        function rollCheckBoxValueChanged(app, event)
            value = app.rollCheckBox.Value;
            if value == 0
                app.iks(2).Visible="off";
            else
                app.iks(2).Visible="on";
            end
        end

        % Value changed function: dpitchCheckBox
        function dpitchCheckBoxValueChanged(app, event)
            value = app.dpitchCheckBox.Value;
            if value == 0
                app.iks(3).Visible="off";
            else
                app.iks(3).Visible="on";
            end
        end

        % Value changed function: pitchCheckBox
        function pitchCheckBoxValueChanged(app, event)
            value = app.pitchCheckBox.Value;
            if value == 0
                app.iks(4).Visible="off";
            else
                app.iks(4).Visible="on";
            end
        end

        % Value changed function: dyawCheckBox
        function dyawCheckBoxValueChanged(app, event)
            value = app.dyawCheckBox.Value;
            if value == 0
                app.iks(5).Visible="off";
            else
                app.iks(5).Visible="on";
            end
        end

        % Value changed function: yawCheckBox
        function yawCheckBoxValueChanged(app, event)
            value = app.yawCheckBox.Value;
            if value == 0
                app.iks(6).Visible="off";
            else
                app.iks(6).Visible="on";
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value ==0
                app.Panel.Enable="on";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'BOTH', 'ONLY POSITION', 'ONLYANGLE', 'CLOSE'};
            app.DropDown.ItemsData = [1 2 3 4];
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 1;
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.UIAxesE = uiaxes(app.Panel);
            title(app.UIAxesE, 'E')
            xlabel(app.UIAxesE, 'X')
            ylabel(app.UIAxesE, 'Y')
            zlabel(app.UIAxesE, 'Z')
            app.dxCheckBox = uicheckbox(app.Panel);
            app.dxCheckBox.ValueChangedFcn = createCallbackFcn(app, @dxCheckBoxValueChanged, true);
            app.dxCheckBox.Text = 'dx';
            app.xCheckBox = uicheckbox(app.Panel);
            app.xCheckBox.ValueChangedFcn = createCallbackFcn(app, @xCheckBoxValueChanged, true);
            app.xCheckBox.Text = 'x';
            app.dyCheckBox = uicheckbox(app.Panel);
            app.dyCheckBox.ValueChangedFcn = createCallbackFcn(app, @dyCheckBoxValueChanged, true);
            app.dyCheckBox.Text = 'dy';
            app.yCheckBox = uicheckbox(app.Panel);
            app.yCheckBox.ValueChangedFcn = createCallbackFcn(app, @yCheckBoxValueChanged, true);
            app.yCheckBox.Text = 'y';
            app.dzCheckBox = uicheckbox(app.Panel);
            app.dzCheckBox.ValueChangedFcn = createCallbackFcn(app, @dzCheckBoxValueChanged, true);
            app.dzCheckBox.Text = 'dz';
            app.zCheckBox = uicheckbox(app.Panel);
            app.zCheckBox.ValueChangedFcn = createCallbackFcn(app, @zCheckBoxValueChanged, true);
            app.zCheckBox.Text = 'z';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.UIAxesN = uiaxes(app.Panel2);
            title(app.UIAxesN, 'N')
            xlabel(app.UIAxesN, 'X')
            ylabel(app.UIAxesN, 'Y')
            zlabel(app.UIAxesN, 'Z')
            app.drollCheckBox = uicheckbox(app.Panel2);
            app.drollCheckBox.ValueChangedFcn = createCallbackFcn(app, @drollCheckBoxValueChanged, true);
            app.drollCheckBox.Text = 'droll';
            app.rollCheckBox = uicheckbox(app.Panel2);
            app.rollCheckBox.ValueChangedFcn = createCallbackFcn(app, @rollCheckBoxValueChanged, true);
            app.rollCheckBox.Text = 'roll';
            app.dpitchCheckBox = uicheckbox(app.Panel2);
            app.dpitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @dpitchCheckBoxValueChanged, true);
            app.dpitchCheckBox.Text = 'dpitch';
            app.pitchCheckBox = uicheckbox(app.Panel2);
            app.pitchCheckBox.ValueChangedFcn = createCallbackFcn(app, @pitchCheckBoxValueChanged, true);
            app.pitchCheckBox.Text = 'pitch';
            app.dyawCheckBox = uicheckbox(app.Panel2);
            app.dyawCheckBox.ValueChangedFcn = createCallbackFcn(app, @dyawCheckBoxValueChanged, true);
            app.dyawCheckBox.Text = 'dyaw';
            app.yawCheckBox = uicheckbox(app.Panel2);
            app.yawCheckBox.ValueChangedFcn = createCallbackFcn(app, @yawCheckBoxValueChanged, true);
            app.yawCheckBox.Text = 'yaw';
            app.drollEditFieldLabel = uilabel(app.UIFigure);
            app.drollEditFieldLabel.Text = 'droll';
            app.drollEditField = uieditfield(app.UIFigure, 'numeric');
            app.dpitchEditFieldLabel = uilabel(app.UIFigure);
            app.dpitchEditFieldLabel.Text = 'dpitch';
            app.dpitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.rollEditFieldLabel = uilabel(app.UIFigure);
            app.rollEditFieldLabel.Text = 'roll';
            app.rollEditField = uieditfield(app.UIFigure, 'numeric');
            app.pitchEditFieldLabel = uilabel(app.UIFigure);
            app.pitchEditFieldLabel.Text = 'pitch';
            app.pitchEditField = uieditfield(app.UIFigure, 'numeric');
            app.dyawEditFieldLabel = uilabel(app.UIFigure);
            app.dyawEditFieldLabel.Text = 'dyaw';
            app.dyawEditField = uieditfield(app.UIFigure, 'numeric');
            app.dyEditFieldLabel = uilabel(app.UIFigure);
            app.dyEditFieldLabel.Text = 'dy';
            app.dyEditField = uieditfield(app.UIFigure, 'numeric');
            app.dxEditFieldLabel = uilabel(app.UIFigure);
            app.dxEditFieldLabel.Text = 'dx';
            app.dxEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.yawEditFieldLabel = uilabel(app.UIFigure);
            app.yawEditFieldLabel.Text = 'yaw';
            app.yawEditField = uieditfield(app.UIFigure, 'numeric');
            app.dzEditFieldLabel = uilabel(app.UIFigure);
            app.dzEditFieldLabel.Text = 'dz';
            app.dzEditField = uieditfield(app.UIFigure, 'numeric');
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.zEditFieldLabel = uilabel(app.UIFigure);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.UIFigure, 'numeric');
            app.w1EditFieldLabel = uilabel(app.UIFigure);
            app.w1EditFieldLabel.Text = 'w1';
            app.w1EditField = uieditfield(app.UIFigure, 'numeric');
            app.w2EditFieldLabel = uilabel(app.UIFigure);
            app.w2EditFieldLabel.Text = 'w2';
            app.w2EditField = uieditfield(app.UIFigure, 'numeric');
            app.w3EditFieldLabel = uilabel(app.UIFigure);
            app.w3EditFieldLabel.Text = 'w3';
            app.w3EditField = uieditfield(app.UIFigure, 'numeric');
            app.w4EditFieldLabel = uilabel(app.UIFigure);
            app.w4EditFieldLabel.Text = 'w4';
            app.w4EditField = uieditfield(app.UIFigure, 'numeric');
            app.LOADVARIABLEButton = uibutton(app.UIFigure, 'push');
            app.LOADVARIABLEButton.ButtonPushedFcn = createCallbackFcn(app, @LOADVARIABLEButtonPushed, true);
            app.LOADVARIABLEButton.Text = 'LOAD VARIABLE';
            app.StartSimButton = uibutton(app.UIFigure, 'push');
            app.StartSimButton.ButtonPushedFcn = createCallbackFcn(app, @StartSimButtonPushed, true);
            app.StartSimButton.Text = 'Start Sim';
            app.stoptimeEditFieldLabel = uilabel(app.UIFigure);
            app.stoptimeEditFieldLabel.Text = 'stop time';
            app.stoptimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.fixedstepEditFieldLabel = uilabel(app.UIFigure);
            app.fixedstepEditFieldLabel.Text = 'fixed step';
            app.fixedstepEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DRONEMEAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end